classdef ADSB_Main_Plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        ExportFilteredInformationPanel  matlab.ui.container.Panel
        ExportFileButton                matlab.ui.control.Button
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        BottomTabs                      matlab.ui.container.TabGroup
        LatitudeTab                     matlab.ui.container.Tab
        LatAxes                         matlab.ui.control.UIAxes
        LongitudeTab                    matlab.ui.container.Tab
        LonAxes                         matlab.ui.control.UIAxes
        AltitudeandGroundSpeedTab       matlab.ui.container.Tab
        TimeWindowPanel                 matlab.ui.container.Panel
        SelectEndHourDropDown           matlab.ui.control.DropDown
        SelectEndHourDropDownLabel      matlab.ui.control.Label
        SelectStartHourDropDown         matlab.ui.control.DropDown
        SelectStartHourDropDownLabel    matlab.ui.control.Label
        AircraftInformationPanel        matlab.ui.container.Panel
        DurationofFlightEditField       matlab.ui.control.EditField
        DurationofFlightEditFieldLabel  matlab.ui.control.Label
        TOCEditField                    matlab.ui.control.NumericEditField
        TOCEditFieldLabel               matlab.ui.control.Label
        EndTimeEditField                matlab.ui.control.EditField
        EndTimeEditFieldLabel           matlab.ui.control.Label
        StartTimeEditField              matlab.ui.control.EditField
        StartTimeEditFieldLabel         matlab.ui.control.Label
        TailNumberEditField             matlab.ui.control.EditField
        TailNumberEditFieldLabel        matlab.ui.control.Label
        AircraftModelEditField          matlab.ui.control.EditField
        AircraftModelEditFieldLabel     matlab.ui.control.Label
        SelectAircraftDropDown          matlab.ui.control.DropDown
        SelectAircraftDropDownLabel     matlab.ui.control.Label
        FilterByAircraftPanel           matlab.ui.container.Panel
        SelectAircraftListBox           matlab.ui.control.ListBox
        SelectAllAircraftButton         matlab.ui.control.Button
        TopTabs                         matlab.ui.container.TabGroup
        TopoMapTab                      matlab.ui.container.Tab
        AerialMapTab                    matlab.ui.container.Tab
        AerialAxes                      matlab.ui.control.UIAxes
        AnimationTab                    matlab.ui.container.Tab
        CheckVideoDirectoryforResultingVideoLabel  matlab.ui.control.Label
        VideoFileType                   matlab.ui.control.DropDown
        SpecifyFileTypeDropDownLabel    matlab.ui.control.Label
        FrameRate                       matlab.ui.control.NumericEditField
        SpecifyFrameRatefpsEditFieldLabel  matlab.ui.control.Label
        VideoParametersLabel            matlab.ui.control.Label
        DetermineDropDown               matlab.ui.control.DropDown
        DetermineDropDownLabel          matlab.ui.control.Label
        VideoFilename                   matlab.ui.control.EditField
        SpecifyVideoFileNameEditFieldLabel  matlab.ui.control.Label
        IncludeMovingCentroidLocationSwitch  matlab.ui.control.Switch
        IncludeMovingCentroidLocationLabel  matlab.ui.control.Label
        CreateVideoButton               matlab.ui.control.Button
        Markers                         matlab.ui.container.ButtonGroup
        MarkersByButtonGroup            matlab.ui.container.ButtonGroup
        NIC7Button                      matlab.ui.control.RadioButton
        AircraftNumberButton            matlab.ui.control.RadioButton
        NICValueButton                  matlab.ui.control.RadioButton
        MinNICValueSpinner              matlab.ui.control.Spinner
        MinNICValueSpinnerLabel         matlab.ui.control.Label
        MaxNICValueSpinner              matlab.ui.control.Spinner
        MaxNICValueSpinnerLabel         matlab.ui.control.Label
        UploadFilesButton               matlab.ui.control.Button
    end


    properties (Access = public)
        %Data sources
        flightdata=1; 
        filteredata=1; 
        hours=[];
        boxLon=[-107 -103];
        boxLat=[38.5 41];
        %Selected Aircraft
        aircraft='';
        num_aircraft=1;
        %NIC value criteria
        min_nic=0;
        max_nic=11;
        %marker information
        markertype='';
        %directory/path information
        current_dir=cd;
        registry_dir=strcat(cd,'\aircraftDatabase_OpenSky.csv');
        reg_data=[];
        gx=[];
    end

    methods (Access = private)
        %Plot Position Flight Data
        function PlotData(app)
%             app.boxLon=[min(app.filteredata.lon),max(app.filteredata.lon)];
%             app.boxLat=[min(app.filteredata.lat),max(app.filteredata.lat)];
            %Plot the Topographic Map
            if class(app.gx)=="matlab.graphics.axis.GeographicAxes"
                cla(app.gx,'reset')
            else
                app.gx=geoaxes(app.TopoMapTab);
            end
            ADSBtools.plot.plot_geo(app.gx,app.filteredata.lat,app.filteredata.lon,app.filteredata.time,...
                app.filteredata.nic,app.filteredata.icao,app.markertype,app.boxLon,app.boxLat)
            %Plot the 3D map

            
%             ADSBtools.plot.plot_3D(app.AerialAxes,app.filteredata.time, app.filteredata.lon,...
%                 app.filteredata.lat,app.filteredata.alt, app.filteredata.nic,...
%                 app.filteredata.icao, boxLon,boxLat,1)

            %Plot the Latitude Axes
            ADSBtools.plot.plot_2D_LatAlt(app.LatAxes,app.filteredata.time, app.filteredata.lat,...
                app.filteredata.alt, app.filteredata.nic, app.filteredata.icao, app.markertype)
            %Plot the Longitude Axes
            ADSBtools.plot.plot_2D_LonAlt(app.LonAxes,app.filteredata.time, app.filteredata.lon,...
                app.filteredata.alt, app.filteredata.nic, app.filteredata.icao, app.markertype)
            %Plot the groundspeed and altitude plot (for selected aircraft
            %only)


        end

        %Refresh Aircraft Data List in ListBox
        function RefreshAC(app)
            %Reset List
            app.SelectAircraftListBox.Items={};
            %Add Aircraft to ListBox
            app.aircraft=unique(app.filteredata.icao);
            app.num_aircraft=length(app.aircraft);
            for i=1:app.num_aircraft
                app.SelectAircraftListBox.Items(i)=app.aircraft(i);
            end
        end

        %Resets Data Display in GUI
        function ResetData(app)
            app.filteredata=app.flightdata;
            app.min_nic=min(app.flightdata.nic);
            app.max_nic=max(app.flightdata.nic);
            app.MinNICValueSpinner.Value=app.min_nic;
            app.MaxNICValueSpinner.Value=app.max_nic;
            app.SelectStartHourDropDown.Value=string(app.hours(1));
            app.SelectEndHourDropDown.Value=string(app.hours(end));
        end
        
        %Determine Marker Type
        function MarkerType(app)  
            if app.NICValueButton.Value==1
                app.markertype='NIC';
            elseif app.AircraftNumberButton.Value==1
                app.markertype='ICAO';
            elseif app.NIC7Button.Value==1
                app.markertype='Interfere';
            end
        end
        
        %Upload OpenSky Registry File
        function RegistryUpload(app)
            app.reg_data=readtable(app.registry_dir);
        end
        
        
        function SelectFile(app)
            [file_csv,path_csv] = uigetfile(strcat(app.current_dir,'\*.csv'),...
                'MultiSelect','on');
            %If user chose one file
            if numel(string(file_csv))==1
                app.flightdata=readtable([path_csv,file_csv],'Format','auto');
                app.flightdata.nic=round(app.flightdata.nic);
                if ismember("mintime",app.flightdata.Properties.VariableNames) 
                        app.flightdata = renamevars(app.flightdata,"mintime","time");
                end
                if ismember("icao24",app.flightdata.Properties.VariableNames)
                    app.flightdata=renamevars(app.flightdata,"icao24","icao");
                end
            %If user chose multiple files
            else
                hourdata=cell(1,numel(file_csv));
                for i=1:numel(file_csv)
                    table=readtable([path_csv,file_csv{i}],'Format','auto');
                    t_ind=find(string(table.Properties.VariableNames)=="time");
                    lat_ind=find(string(table.Properties.VariableNames)=="lat");
                    lon_ind=find(string(table.Properties.VariableNames)=="lon");
                    alt_ind=find(string(table.Properties.VariableNames)=="alt");
                    nic_ind=find(string(table.Properties.VariableNames)=="nic");
                    icao_ind=find(string(table.Properties.VariableNames)=="icao");
                    if isempty(t_ind)
                        t_ind=find(string(table.Properties.VariableNames)=="mintime");

                    elseif isempty(icao_ind)
                        icao_ind=find(string(table.Properties.VariableNames)=="icao24");
                    end
                    hourdata{i}=table(:,[t_ind,icao_ind,lat_ind,lon_ind,alt_ind, ...
                        nic_ind]);
                    if ismember("mintime",table.Properties.VariableNames) 
                            app.flightdata = renamevars(hourdata{i},"mintime","time");
                    end
                    if ismember("icao24",table.Properties.VariableNames)
                        app.flightdata=renamevars(hourdata{i},"icao24","icao");
                    end
                    
                    fprintf("Hour %s\n",num2str(i))

                end
                app.flightdata=vertcat(hourdata{:});
                app.flightdata.nic=round(app.flightdata.nic);
            end
            app.flightdata=app.flightdata((app.flightdata.lat<=app.boxLat(2) & app.flightdata.lat>app.boxLat(1)...
                &app.flightdata.lon<=app.boxLon(2) & app.flightdata.lon>=app.boxLon(1)),:);
        end
        
        function HourSelect(app)
            app.hours=unique(hms(app.flightdata.time));
            [app.SelectStartHourDropDown.Items,app.SelectEndHourDropDown.Items]=deal(string(app.hours));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %load registry directory
            RegistryUpload(app)
        end

        % Button pushed function: UploadFilesButton
        function UploadFilesButtonPushed(app, event)
            %Select File (ALSO NEED TO MODIFY CODE TO ACCOMMODATE DATASETS
            %WITH DIFFERENT COLUMN FORMATS)
            SelectFile(app);
            %Set hours discretely in start/end hour time window
            HourSelect(app)
            %Reset Data in GUI
            ResetData(app)
            %Determine Marker Type
            MarkerType(app)
            %Plot Data
            PlotData(app)
            %Refresh Aircraft List
            RefreshAC(app)

            %Add filename to export file
            app.FilenameEditField.Value=[path_csv, file_csv];
        end

        % Value changed function: MinNICValueSpinner
        function MinNICValueSpinnerValueChanged(app, event)
            app.min_nic = app.MinNICValueSpinner.Value;
            app.filteredata=app.flightdata(app.flightdata.nic>=app.min_nic...
                & app.flightdata.nic<=app.max_nic,:);
            %Determine Marker Type
            MarkerType(app)
            %Refresh Aircraft
            RefreshAC(app)
            %Refresh Plots
            PlotData(app)
        end

        % Value changed function: MaxNICValueSpinner
        function MaxNICValueSpinnerValueChanged(app, event)
            app.max_nic = app.MaxNICValueSpinner.Value;
            app.filteredata=app.flightdata(app.flightdata.nic>=app.min_nic...
                & app.flightdata.nic<=app.max_nic,:);
            %Determine Marker Type
            MarkerType(app)
            %Refresh Aircraft
            RefreshAC(app)
            %Refresh Plots
            PlotData(app)
        end

        % Callback function: SelectAircraftDropDown,
        % SelectAircraftDropDown, 
        % ...and 1 other component
        function SelectAircraftListBoxValueChanged(app, event)
            filt_aircraft = app.SelectAircraftListBox.Value;
            app.SelectAircraftDropDown.Items=filt_aircraft;
            app.filteredata=app.flightdata(ismember(app.flightdata.icao,filt_aircraft),:);
            app.min_nic=min(app.filteredata.nic);
            app.max_nic=max(app.filteredata.nic);
            app.MinNICValueSpinner.Value=app.min_nic;
            app.MaxNICValueSpinner.Value=app.max_nic;
            %Determine Marker Type
            MarkerType(app)
            %Refresh Plots
            PlotData(app)
            %Report Aircraft Information
            spec_aircraft = app.SelectAircraftDropDown.Value; 
            %Find Aircraft ICAO Code in Registry
            ac_ind=find(strcmp(app.reg_data.icao24,spec_aircraft));          
            
            %Input Entries
            if isempty(ac_ind)
                app.TailNumberEditField.Value='N/A';
                app.AircraftModelEditField.Value='N/A';
                
            else
                app.TailNumberEditField.Value=app.reg_data.registration{ac_ind};
                app.AircraftModelEditField.Value=app.reg_data.model{ac_ind};
            end
            specdata=app.filteredata(strcmp(app.filteredata.icao,spec_aircraft),:);
            app.TOCEditField.Value=max(specdata.alt);
            app.StartTimeEditField.Value=datestr(specdata.time(1));
            app.EndTimeEditField.Value=datestr(specdata.time(end));
            app.DurationofFlightEditField.Value=string(specdata.time(end)...
                -specdata.time(1));
        end

        % Button pushed function: SelectAllAircraftButton
        function SelectAllAircraftButtonPushed(app, event)
            app.SelectAircraftListBox.Value={};
            %Reset Data in GUI
            ResetData(app)
            %Determine Marker Type
            MarkerType(app)
            %Refresh Plots
            PlotData(app)
            %Reset Aircraft LIst
            RefreshAC(app)

        end

        % Selection changed function: MarkersByButtonGroup
        function MarkerSelectionChange(app, event)
            %Determine Marker Type
            MarkerType(app)
            %Refresh Plots
            PlotData(app)
        end

        % Button pushed function: ExportFileButton
        function ExportFileButtonPushed(app, event)
            writetimetable(app.filteredata,app.FilenameEditField.Value)
        end

        % Button pushed function: CreateVideoButton
        function CreateVideoButtonPushed(app, event)
            %If user does not want centroid plotted, do not include in
            %animation
            if strcmp(app.DetermineDropDown.Enable,"off")==1
                option=0;
            elseif strcmp(app.DetermineDropDown.Value,"Moving Centroid")==1
                option=1;
            else
                option=2;
            end
            % Run animation (data,framerate,option to include centroid, and
            % centroid plot type
            ADSBtools.plot.plot_flightanim(app.filteredata,app.VideoFileType.Value,app.VideoFilename.Value,...
                app.FrameRate.Value,option,app.boxLat,app.boxLon)
        end

        % Value changed function: SelectStartHourDropDown
        function SelectStartHourDropDownValueChanged(app, event)
            chosenh = app.SelectStartHourDropDown.Value;
            %Reset Data
            app.filteredata=app.flightdata(hms(app.flightdata.time)>=str2double(chosenh) &...
                hms(app.flightdata.time)<=str2double(app.SelectEndHourDropDown.Value),:);
            %Determine Marker Type
            MarkerType(app)
            %Reset Aircraft LIst
            RefreshAC(app)
            %Refresh Plots
            PlotData(app)
        end

        % Value changed function: SelectEndHourDropDown
        function SelectEndHourDropDownValueChanged(app, event)
            chosenh = app.SelectEndHourDropDown.Value;
            app.filteredata=app.flightdata(hms(app.flightdata.time)<=str2double(chosenh) &...
                hms(app.flightdata.time)>=str2double(app.SelectStartHourDropDown.Value),:);
            %Determine Marker Type
            MarkerType(app)
            %Reset Aircraft LIst
            RefreshAC(app)
            %Refresh Plots
            PlotData(app)
        end

        % Value changed function: IncludeMovingCentroidLocationSwitch
        function IncludeMovingCentroidLocationSwitchValueChanged(app, event)
            value = app.IncludeMovingCentroidLocationSwitch.Value;
            if strcmp(value,'No')==1
                app.DetermineDropDown.Enable="off";
            else
                app.DetermineDropDown.Enable="on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UploadFilesButton = uibutton(app.GridLayout, 'push');
            app.UploadFilesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFilesButtonPushed, true);
            app.UploadFilesButton.Text = 'Upload Files';
            app.Markers = uibuttongroup(app.GridLayout);
            app.Markers.Title = 'Label Markers By:';
            app.MaxNICValueSpinnerLabel = uilabel(app.Markers);
            app.MaxNICValueSpinnerLabel.Text = 'Max. NIC Value';
            app.MaxNICValueSpinner = uispinner(app.Markers);
            app.MaxNICValueSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxNICValueSpinnerValueChanged, true);
            app.MinNICValueSpinnerLabel = uilabel(app.Markers);
            app.MinNICValueSpinnerLabel.Text = 'Min. NIC Value';
            app.MinNICValueSpinner = uispinner(app.Markers);
            app.MinNICValueSpinner.ValueChangedFcn = createCallbackFcn(app, @MinNICValueSpinnerValueChanged, true);
            app.MarkersByButtonGroup = uibuttongroup(app.Markers);
            app.MarkersByButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MarkerSelectionChange, true);
            app.MarkersByButtonGroup.Title = 'Markers By:';
            app.NICValueButton = uiradiobutton(app.MarkersByButtonGroup);
            app.NICValueButton.Text = 'NIC Value';
            app.NICValueButton.Value = true;
            app.AircraftNumberButton = uiradiobutton(app.MarkersByButtonGroup);
            app.AircraftNumberButton.Text = 'Aircraft Number';
            app.NIC7Button = uiradiobutton(app.MarkersByButtonGroup);
            app.NIC7Button.Text = 'NIC<7';
            app.TopTabs = uitabgroup(app.GridLayout);
            app.TopoMapTab = uitab(app.TopTabs);
            app.TopoMapTab.Title = 'TopoPlot';
            app.AerialMapTab = uitab(app.TopTabs);
            app.AerialMapTab.Title = '3D Map';
            app.AerialAxes = uiaxes(app.AerialMapTab);
            xlabel(app.AerialAxes, 'Longitude [degrees]')
            ylabel(app.AerialAxes, 'Latitude [degrees]')
            zlabel(app.AerialAxes, 'Altitude [meters]')
            app.AnimationTab = uitab(app.TopTabs);
            app.AnimationTab.Title = 'Animation';
            app.CreateVideoButton = uibutton(app.AnimationTab, 'push');
            app.CreateVideoButton.ButtonPushedFcn = createCallbackFcn(app, @CreateVideoButtonPushed, true);
            app.CreateVideoButton.Text = 'Create Video';
            app.IncludeMovingCentroidLocationLabel = uilabel(app.AnimationTab);
            app.IncludeMovingCentroidLocationLabel.Text = 'Include Moving Centroid Location?';
            app.IncludeMovingCentroidLocationSwitch = uiswitch(app.AnimationTab, 'slider');
            app.IncludeMovingCentroidLocationSwitch.Items = {'No', 'Yes'};
            app.IncludeMovingCentroidLocationSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludeMovingCentroidLocationSwitchValueChanged, true);
            app.IncludeMovingCentroidLocationSwitch.Value = 'No';
            app.SpecifyVideoFileNameEditFieldLabel = uilabel(app.AnimationTab);
            app.SpecifyVideoFileNameEditFieldLabel.Text = 'Specify Video File Name';
            app.VideoFilename = uieditfield(app.AnimationTab, 'text');
            app.DetermineDropDownLabel = uilabel(app.AnimationTab);
            app.DetermineDropDownLabel.Text = 'Determine ';
            app.DetermineDropDown = uidropdown(app.AnimationTab);
            app.DetermineDropDown.Items = {'Moving Centroid', 'Kernel Density'};
            app.DetermineDropDown.Value = 'Moving Centroid';
            app.VideoParametersLabel = uilabel(app.AnimationTab);
            app.VideoParametersLabel.Text = 'Video Parameters';
            app.SpecifyFrameRatefpsEditFieldLabel = uilabel(app.AnimationTab);
            app.SpecifyFrameRatefpsEditFieldLabel.Text = 'Specify Frame Rate (fps)';
            app.FrameRate = uieditfield(app.AnimationTab, 'numeric');
            app.SpecifyFileTypeDropDownLabel = uilabel(app.AnimationTab);
            app.SpecifyFileTypeDropDownLabel.Text = 'Specify File Type';
            app.VideoFileType = uidropdown(app.AnimationTab);
            app.VideoFileType.Items = {'Uncompressed AVI', 'Motion JPEG AVI', 'MPEG-4'};
            app.VideoFileType.Value = 'Uncompressed AVI';
            app.CheckVideoDirectoryforResultingVideoLabel = uilabel(app.AnimationTab);
            app.CheckVideoDirectoryforResultingVideoLabel.Text = '(Check ''Video Directory'' for Resulting Video)';
            app.FilterByAircraftPanel = uipanel(app.GridLayout);
            app.FilterByAircraftPanel.Title = 'Filter By Aircraft';
            app.SelectAllAircraftButton = uibutton(app.FilterByAircraftPanel, 'push');
            app.SelectAllAircraftButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllAircraftButtonPushed, true);
            app.SelectAllAircraftButton.Text = 'Select All Aircraft';
            app.SelectAircraftListBox = uilistbox(app.FilterByAircraftPanel);
            app.SelectAircraftListBox.Items = {};
            app.SelectAircraftListBox.Multiselect = 'on';
            app.SelectAircraftListBox.ValueChangedFcn = createCallbackFcn(app, @SelectAircraftListBoxValueChanged, true);
            app.SelectAircraftListBox.Value = {};
            app.AircraftInformationPanel = uipanel(app.GridLayout);
            app.AircraftInformationPanel.Title = 'Aircraft Information';
            app.SelectAircraftDropDownLabel = uilabel(app.AircraftInformationPanel);
            app.SelectAircraftDropDownLabel.Text = 'Select Aircraft';
            app.SelectAircraftDropDown = uidropdown(app.AircraftInformationPanel);
            app.SelectAircraftDropDown.Items = {};
            app.SelectAircraftDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectAircraftListBoxValueChanged, true);
            app.SelectAircraftDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAircraftListBoxValueChanged, true);
            app.SelectAircraftDropDown.Value = {};
            app.AircraftModelEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.AircraftModelEditFieldLabel.Text = 'Aircraft Model';
            app.AircraftModelEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.TailNumberEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.TailNumberEditFieldLabel.Text = 'Tail Number';
            app.TailNumberEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.StartTimeEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.EndTimeEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.EndTimeEditFieldLabel.Text = 'End Time';
            app.EndTimeEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.TOCEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.TOCEditFieldLabel.Text = 'TOC';
            app.TOCEditField = uieditfield(app.AircraftInformationPanel, 'numeric');
            app.DurationofFlightEditFieldLabel = uilabel(app.AircraftInformationPanel);
            app.DurationofFlightEditFieldLabel.Text = 'Duration of Flight';
            app.DurationofFlightEditField = uieditfield(app.AircraftInformationPanel, 'text');
            app.TimeWindowPanel = uipanel(app.GridLayout);
            app.TimeWindowPanel.Title = 'Time Window';
            app.SelectStartHourDropDownLabel = uilabel(app.TimeWindowPanel);
            app.SelectStartHourDropDownLabel.Text = 'Select Start Hour';
            app.SelectStartHourDropDown = uidropdown(app.TimeWindowPanel);
            app.SelectStartHourDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectStartHourDropDownValueChanged, true);
            app.SelectEndHourDropDownLabel = uilabel(app.TimeWindowPanel);
            app.SelectEndHourDropDownLabel.Text = 'Select End Hour';
            app.SelectEndHourDropDown = uidropdown(app.TimeWindowPanel);
            app.SelectEndHourDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectEndHourDropDownValueChanged, true);
            app.BottomTabs = uitabgroup(app.GridLayout);
            app.LatitudeTab = uitab(app.BottomTabs);
            app.LatitudeTab.Title = 'Latitude';
            app.LatAxes = uiaxes(app.LatitudeTab);
            xlabel(app.LatAxes, 'Latitude [degrees]')
            ylabel(app.LatAxes, 'Baro Altitude [m]')
            zlabel(app.LatAxes, 'Z')
            app.LongitudeTab = uitab(app.BottomTabs);
            app.LongitudeTab.Title = 'Longitude';
            app.LonAxes = uiaxes(app.LongitudeTab);
            xlabel(app.LonAxes, 'Longitude [degrees]')
            ylabel(app.LonAxes, 'Baro Altitude [m]')
            zlabel(app.LonAxes, 'Z')
            app.AltitudeandGroundSpeedTab = uitab(app.BottomTabs);
            app.AltitudeandGroundSpeedTab.Title = 'Altitude and Ground Speed';
            app.ExportFilteredInformationPanel = uipanel(app.GridLayout);
            app.ExportFilteredInformationPanel.Title = 'Export Filtered Information';
            app.FilenameEditFieldLabel = uilabel(app.ExportFilteredInformationPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.ExportFilteredInformationPanel, 'text');
            app.ExportFileButton = uibutton(app.ExportFilteredInformationPanel, 'push');
            app.ExportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFileButtonPushed, true);
            app.ExportFileButton.Text = 'Export File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADSB_Main_Plot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end